classdef GUITrain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FEATURESTOSELECTButtonGroup    matlab.ui.container.ButtonGroup
        AsthmaCheckBox                 matlab.ui.control.CheckBox
        SleepTimeCheckBox              matlab.ui.control.CheckBox
        GenHealthCheckBox              matlab.ui.control.CheckBox
        AgeCategoryCheckBox            matlab.ui.control.CheckBox
        DiffWalkingCheckBox            matlab.ui.control.CheckBox
        RaceCheckBox                   matlab.ui.control.CheckBox
        PhysicalHealthCheckBox         matlab.ui.control.CheckBox
        MentalHealthCheckBox           matlab.ui.control.CheckBox
        AlcoholDrinkingCheckBox        matlab.ui.control.CheckBox
        SmokingCheckBox                matlab.ui.control.CheckBox
        BMI5CheckBox                   matlab.ui.control.CheckBox
        StrokeCheckBox                 matlab.ui.control.CheckBox
        PhysicalActivityCheckBox       matlab.ui.control.CheckBox
        DiabeticCheckBox               matlab.ui.control.CheckBox
        SexCheckBox                    matlab.ui.control.CheckBox
        HELPButton                     matlab.ui.control.Button
        CLASSIFIERButtonGroup          matlab.ui.container.ButtonGroup
        TRAINButton                    matlab.ui.control.Button
        NumberofneighborsSlider        matlab.ui.control.Slider
        NumberofneighborsSliderLabel   matlab.ui.control.Label
        SupportVectorsMachineNonlinearButton  matlab.ui.control.RadioButton
        NaiveBayesButton               matlab.ui.control.RadioButton
        SupportVectorsMachineLinearButton  matlab.ui.control.RadioButton
        KNearestNeighborsButton        matlab.ui.control.RadioButton
        FisherLDAButton                matlab.ui.control.RadioButton
        MinimumDistanceClassifierMahalanobisButton  matlab.ui.control.RadioButton
        MinimumDistanceClassifierEuclideanButton  matlab.ui.control.RadioButton
        DIMENSIONALITYREDUCTIONButtonGroup  matlab.ui.container.ButtonGroup
        NoneButton_3                   matlab.ui.control.RadioButton
        NumberofcomponentsSlider       matlab.ui.control.Slider
        NumberofcomponentsSliderLabel  matlab.ui.control.Label
        LDAButton                      matlab.ui.control.RadioButton
        PCAButton                      matlab.ui.control.RadioButton
        PatternRecognition2122Label    matlab.ui.control.Label
        SCENARIOButtonGroup            matlab.ui.container.ButtonGroup
        CButton                        matlab.ui.control.RadioButton
        BButton                        matlab.ui.control.RadioButton
        AButton                        matlab.ui.control.RadioButton
        CatarinaPintoGabrielPinheiroJulianaFariaLabel  matlab.ui.control.Label
        HeartDiseasePredictionLabel    matlab.ui.control.Label
        FEATURESELECTIONButtonGroup    matlab.ui.container.ButtonGroup
        ShowgraphsButton               matlab.ui.control.Button
        NoneButton_2                   matlab.ui.control.RadioButton
        ROCcurveButton                 matlab.ui.control.RadioButton
        CorrelationMatrixButton        matlab.ui.control.RadioButton
        KruskalWallisButton            matlab.ui.control.RadioButton
        RESULTSVALIDATIONSETPanel      matlab.ui.container.Panel
        SpecificityEditField           matlab.ui.control.NumericEditField
        SpecificityEditFieldLabel      matlab.ui.control.Label
        FScoreEditField_3              matlab.ui.control.NumericEditField
        FScoreEditField_3Label         matlab.ui.control.Label
        PrecisionEditField_3           matlab.ui.control.NumericEditField
        PrecisionEditField_3Label      matlab.ui.control.Label
        SensitivityEditField_3         matlab.ui.control.NumericEditField
        SensitivityEditField_3Label    matlab.ui.control.Label
        FScoreEditField_2              matlab.ui.control.NumericEditField
        FScoreEditField_2Label         matlab.ui.control.Label
        PrecisionEditField_2           matlab.ui.control.NumericEditField
        PrecisionEditField_2Label      matlab.ui.control.Label
        SensitivityEditField_2         matlab.ui.control.NumericEditField
        SensitivityEditField_2Label    matlab.ui.control.Label
        FScoreEditField                matlab.ui.control.NumericEditField
        FScoreEditFieldLabel           matlab.ui.control.Label
        PrecisionEditField             matlab.ui.control.NumericEditField
        PrecisionEditFieldLabel        matlab.ui.control.Label
        SensitivityEditField           matlab.ui.control.NumericEditField
        SensitivityEditFieldLabel      matlab.ui.control.Label
        CLASS3Label                    matlab.ui.control.Label
        CLASS2Label                    matlab.ui.control.Label
        CLASS1Label                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        scenario = 'A';
        feature_sel = 'cor';
        
        dim_red = 'PCA';
        n_comp = 1;
        
        classifier = 'mdc-euc';

        neighbors = 5;
        
        metric_value = [];

        indexes = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TRAINButton
        function TRAINButtonPushed(app, event)
            app.RESULTSVALIDATIONSETPanel.Enable ="on";
            app.metric_value = main(app.scenario, app.indexes, app.dim_red, app.n_comp, app.classifier, app.neighbors);
            
            if strcmp(app.scenario, 'A') || strcmp(app.scenario, 'B') 

                app.FScoreEditField.Visible = "off";
                app.FScoreEditFieldLabel.Visible = "off";
                app.PrecisionEditField.Visible = "off";
                app.PrecisionEditFieldLabel.Visible = "off";
                app.SensitivityEditField.Visible = "off";
                app.SensitivityEditFieldLabel.Visible = "off";
                app.CLASS1Label.Visible = "off";
                app.FScoreEditField_2.Enable = "on";
                app.FScoreEditField_2Label.Enable = "on";
                app.PrecisionEditField_2.Enable = "on";
                app.PrecisionEditField_2Label.Enable = "on";
                app.SensitivityEditField_2.Enable = "on";
                app.SensitivityEditField_2Label.Enable = "on";
                app.CLASS2Label.Visible = "off";
                app.FScoreEditField_3.Visible = "off";
                app.FScoreEditField_3Label.Visible = "off";
                app.PrecisionEditField_3.Visible = "off";
                app.PrecisionEditField_3Label.Visible = "off";
                app.SensitivityEditField_3.Visible = "off";
                app.SensitivityEditField_3Label.Visible = "off";
                app.CLASS3Label.Visible = "off";

                app.FScoreEditField_2.Value = app.metric_value(3);
                app.PrecisionEditField_2.Value = app.metric_value(2);
                app.SensitivityEditField_2.Value = app.metric_value(1);
                app.SpecificityEditField.Value = app.metric_value(4);

            else
                app.FScoreEditField.Enable = "on";
                app.FScoreEditFieldLabel.Enable = "on";
                app.PrecisionEditField.Enable = "on";
                app.PrecisionEditFieldLabel.Enable = "on";
                app.SensitivityEditField.Enable = "on";
                app.SensitivityEditFieldLabel.Enable = "on";
                app.CLASS1Label.Enable = "on";
                app.FScoreEditField_2.Enable = "on";
                app.FScoreEditField_2Label.Enable = "on";
                app.PrecisionEditField_2.Enable = "on";
                app.PrecisionEditField_2Label.Enable = "on";
                app.SensitivityEditField_2.Enable = "on";
                app.SensitivityEditField_2Label.Enable = "on";
                app.CLASS2Label.Enable = "on";
                app.FScoreEditField_3.Enable = "on";
                app.FScoreEditField_3Label.Enable = "on";
                app.PrecisionEditField_3.Enable = "on";
                app.PrecisionEditField_3Label.Enable = "on";
                app.SensitivityEditField_3.Enable = "on";
                app.SensitivityEditField_3Label.Enable = "on";
                app.CLASS3Label.Enable = "on";

                app.FScoreEditField_1.Value = app.metric_value(3);
                app.PrecisionEditField_1.Value = app.metric_value(2);
                app.SensitivityEditField_1.Value = app.metric_value(1);
                app.FScoreEditField_2.Value = app.metric_value(6);
                app.PrecisionEditField_2.Value = app.metric_value(5);
                app.SensitivityEditField_2.Value = app.metric_value(4);
                app.FScoreEditField_3.Value = app.metric_value(9);
                app.PrecisionEditField_3.Value = app.metric_value(8);
                app.SensitivityEditField_3.Value = app.metric_value(7);

            end

        end

        % Callback function
        function HELPButtonPCAPushed(app, event)
            msgbox('pca help','Help', 'help');
        end

        % Selection changed function: CLASSIFIERButtonGroup
        function CLASSIFIERButtonGroupSelectionChanged(app, event)
            
            value = app.CLASSIFIERButtonGroup.SelectedObject.Text;

            if strcmp(value, 'K-Nearest Neighbors') 
                app.NumberofneighborsSlider.Enable = "on";
                app.NumberofneighborsSliderLabel.Enable = "on";
            else
                app.NumberofneighborsSlider.Enable = "off";
                app.NumberofneighborsSliderLabel.Enable = "off";
            end
            
            if strcmp(value, 'Minimum Distance Classifier - Euclidean') 
                app.classifier = 'mdc-euc';

            elseif strcmp(value, 'Minimum Distance Classifier - Mahalanobis') 
                app.classifier = 'mdc-mah';
                
            elseif strcmp(value, 'Fisher LDA') 
                app.classifier = 'fisher';
                
            elseif strcmp(value, 'K-Nearest Neighbors') 
                app.classifier = 'knn';
                
            elseif strcmp(value, 'Support Vectors Machine - Linear') 
                app.classifier = 'svm-linear';

            elseif strcmp(value, 'Support Vectors Machine - Non-linear') 
                app.classifier = 'svm-nonlinear';

            elseif strcmp(value, 'Naive Bayes') 
                app.classifier = 'bayes';
            end

        end

        % Selection changed function: DIMENSIONALITYREDUCTIONButtonGroup
        function DIMENSIONALITYREDUCTIONButtonGroupSelectionChanged(app, event)
            
            value = app.DIMENSIONALITYREDUCTIONButtonGroup.SelectedObject.Text;
            
            app.dim_red = value;

            if strcmp(value, 'LDA') 
                app.MinimumDistanceClassifierEuclideanButton.Enable = 'off';
                app.MinimumDistanceClassifierMahalanobisButton.Enable = 'off';
                app.NumberofcomponentsSlider.Enable = "off";
                app.NumberofcomponentsSliderLabel.Enable = "off";
            else
                app.MinimumDistanceClassifierEuclideanButton.Enable = 'on';
                app.MinimumDistanceClassifierMahalanobisButton.Enable = 'on';
                app.NumberofcomponentsSlider.Enable = "on";
                app.NumberofcomponentsSliderLabel.Enable = "on";
            end


        end

        % Value changed function: NumberofcomponentsSlider
        function NumberofcomponentsSliderValueChanged(app, event)
           
            value = app.NumberofcomponentsSlider.Value;
            
            app.n_comp = value;
        end

        % Selection changed function: FEATURESELECTIONButtonGroup
        function FEATURESELECTIONButtonGroupSelectionChanged(app, event)
            value = app.FEATURESELECTIONButtonGroup.SelectedObject.Text;
            
            if strcmp(value, 'Kruskal-Wallis') 
                app.feature_sel = 'kw';

            elseif strcmp(value, 'Correlation Matrix') 
                app.feature_sel = 'cor';
                
            elseif strcmp(value, 'ROC curve') 
                app.feature_sel = 'roc';
               
            end
            

        end

        % Selection changed function: SCENARIOButtonGroup
        function SCENARIOButtonGroupSelectionChanged(app, event)
            value = app.SCENARIOButtonGroup.SelectedObject.Text;
            
            app.scenario = value;
            
        end

        % Button pushed function: HELPButton
        function HELPButtonPushed(app, event)
            msgbox('here we go','Help', 'help');
        end

        % Button pushed function: ShowgraphsButton
        function ShowgraphsButtonPushed(app, event)

            if strcmp(app.feature_sel, 'kw') && strcmp(app.scenario, 'A')
                I = imread('KW_A.jpg');

            elseif strcmp(app.feature_sel, 'kw') && strcmp(app.scenario, 'B')
                I = imread('KW_B.jpg');

            elseif strcmp(app.feature_sel, 'kw') && strcmp(app.scenario, 'C')
                I = imread('KW_C.jpg');

            elseif strcmp(app.feature_sel, 'cor') 
                I = imread('correlation_A.jpg');
                
            elseif strcmp(app.feature_sel, 'roc') && strcmp(app.scenario, 'A')
                I = imread('AUC_A.jpg');

           elseif strcmp(app.feature_sel, 'roc') && strcmp(app.scenario, 'B')
                I = imread('AUC_B.jpg');

           elseif strcmp(app.feature_sel, 'roc') && strcmp(app.scenario, 'C')
                I = imread('AUC_C.jpg');
               
            end

            imshow(I);

        end

        % Value changed function: AsthmaCheckBox
        function AsthmaCheckBoxValueChanged(app, event)
            value = app.AsthmaCheckBox.Value;

            if value == 1 && ~ismember(15,app.indexes)
                app.indexes = [app.indexes, 15];
            end
        end

        % Value changed function: SleepTimeCheckBox
        function SleepTimeCheckBoxValueChanged(app, event)
            value = app.SleepTimeCheckBox.Value;

            if value == 1 && ~ismember(14,app.indexes)
                app.indexes = [app.indexes, 14];
            end
        end

        % Value changed function: GenHealthCheckBox
        function GenHealthCheckBoxValueChanged(app, event)
            value = app.GenHealthCheckBox.Value;
            if value == 1 && ~ismember(13,app.indexes)
                app.indexes = [app.indexes, 13];
            end
        end

        % Value changed function: AgeCategoryCheckBox
        function AgeCategoryCheckBoxValueChanged(app, event)
            value = app.AgeCategoryCheckBox.Value;
            if value == 1 && ~ismember(9,app.indexes)
                app.indexes = [app.indexes, 9];
            end
        end

        % Value changed function: DiffWalkingCheckBox
        function DiffWalkingCheckBoxValueChanged(app, event)
            value = app.DiffWalkingCheckBox.Value;
            if value == 1 && ~ismember(7,app.indexes)
                app.indexes = [app.indexes, 7];
            end
        end

        % Value changed function: RaceCheckBox
        function RaceCheckBoxValueChanged(app, event)
            value = app.RaceCheckBox.Value;
            if value == 1 && ~ismember(10,app.indexes)
                app.indexes = [app.indexes, 10];
            end
        end

        % Value changed function: PhysicalHealthCheckBox
        function PhysicalHealthCheckBoxValueChanged(app, event)
            value = app.PhysicalHealthCheckBox.Value;
            if value == 1 && ~ismember(5,app.indexes)
                app.indexes = [app.indexes, 5];
            end
        end

        % Value changed function: MentalHealthCheckBox
        function MentalHealthCheckBoxValueChanged(app, event)
            value = app.MentalHealthCheckBox.Value;
            if value == 1 && ~ismember(6,app.indexes)
                app.indexes = [app.indexes, 6];
            end
        end

        % Value changed function: AlcoholDrinkingCheckBox
        function AlcoholDrinkingCheckBoxValueChanged(app, event)
            value = app.AlcoholDrinkingCheckBox.Value;
            if value == 1 && ~ismember(3,app.indexes)
                app.indexes = [app.indexes, 3];
            end
        end

        % Value changed function: SmokingCheckBox
        function SmokingCheckBoxValueChanged(app, event)
            value = app.SmokingCheckBox.Value;
            if value == 1 && ~ismember(2,app.indexes)
                app.indexes = [app.indexes, 2];
            end
        end

        % Value changed function: BMI5CheckBox
        function BMI5CheckBoxValueChanged(app, event)
            value = app.BMI5CheckBox.Value;
            if value == 1 && ~ismember(1,app.indexes)
                app.indexes = [app.indexes, 1];
            end
        end

        % Value changed function: StrokeCheckBox
        function StrokeCheckBoxValueChanged(app, event)
            value = app.StrokeCheckBox.Value;
            if value == 1 && ~ismember(4,app.indexes)
                app.indexes = [app.indexes, 4];
            end
        end

        % Value changed function: PhysicalActivityCheckBox
        function PhysicalActivityCheckBoxValueChanged(app, event)
            value = app.PhysicalActivityCheckBox.Value;
            if value == 1 && ~ismember(value,app.indexes)
                app.indexes = [app.indexes, 12];
            end
        end

        % Value changed function: DiabeticCheckBox
        function DiabeticCheckBoxValueChanged(app, event)
            value = app.DiabeticCheckBox.Value;
            if value == 1 && ~ismember(value,app.indexes)
                app.indexes = [app.indexes, 11];
            end
        end

        % Value changed function: SexCheckBox
        function SexCheckBoxValueChanged(app, event)
            value = app.SexCheckBox.Value;
            if value == 1 && ~ismember(value,app.indexes)
                app.indexes = [app.indexes, 8];
            end
        end

        % Callback function
        function ShowPCAgraphsButtonPushed(app, event)
            show_PCA(data, type)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RESULTSVALIDATIONSETPanel = uipanel(app.UIFigure);
            app.RESULTSVALIDATIONSETPanel.Enable = 'off';
            app.RESULTSVALIDATIONSETPanel.Title = 'RESULTS - VALIDATION SET';
            app.CLASS1Label = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.CLASS1Label.Text = 'CLASS 1';
            app.CLASS2Label = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.CLASS2Label.Text = 'CLASS 2';
            app.CLASS3Label = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.CLASS3Label.Text = 'CLASS 3';
            app.SensitivityEditFieldLabel = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.RESULTSVALIDATIONSETPanel, 'numeric');
            app.PrecisionEditFieldLabel = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.PrecisionEditField = uieditfield(app.RESULTSVALIDATIONSETPanel, 'numeric');
            app.FScoreEditFieldLabel = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.FScoreEditFieldLabel.Text = 'F Score';
            app.FScoreEditField = uieditfield(app.RESULTSVALIDATIONSETPanel, 'numeric');
            app.SensitivityEditField_2Label = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.SensitivityEditField_2Label.Text = 'Sensitivity';
            app.SensitivityEditField_2 = uieditfield(app.RESULTSVALIDATIONSETPanel, 'numeric');
            app.PrecisionEditField_2Label = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.PrecisionEditField_2Label.Text = 'Precision';
            app.PrecisionEditField_2 = uieditfield(app.RESULTSVALIDATIONSETPanel, 'numeric');
            app.FScoreEditField_2Label = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.FScoreEditField_2Label.Text = 'F Score';
            app.FScoreEditField_2 = uieditfield(app.RESULTSVALIDATIONSETPanel, 'numeric');
            app.SensitivityEditField_3Label = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.SensitivityEditField_3Label.Text = 'Sensitivity';
            app.SensitivityEditField_3 = uieditfield(app.RESULTSVALIDATIONSETPanel, 'numeric');
            app.PrecisionEditField_3Label = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.PrecisionEditField_3Label.Text = 'Precision';
            app.PrecisionEditField_3 = uieditfield(app.RESULTSVALIDATIONSETPanel, 'numeric');
            app.FScoreEditField_3Label = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.FScoreEditField_3Label.Text = 'F Score';
            app.FScoreEditField_3 = uieditfield(app.RESULTSVALIDATIONSETPanel, 'numeric');
            app.SpecificityEditFieldLabel = uilabel(app.RESULTSVALIDATIONSETPanel);
            app.SpecificityEditFieldLabel.Text = 'Specificity';
            app.SpecificityEditField = uieditfield(app.RESULTSVALIDATIONSETPanel, 'numeric');
            app.FEATURESELECTIONButtonGroup = uibuttongroup(app.UIFigure);
            app.FEATURESELECTIONButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FEATURESELECTIONButtonGroupSelectionChanged, true);
            app.FEATURESELECTIONButtonGroup.Tooltip = {''};
            app.FEATURESELECTIONButtonGroup.Title = 'FEATURE SELECTION';
            app.KruskalWallisButton = uiradiobutton(app.FEATURESELECTIONButtonGroup);
            app.KruskalWallisButton.Text = 'Kruskal-Wallis';
            app.CorrelationMatrixButton = uiradiobutton(app.FEATURESELECTIONButtonGroup);
            app.CorrelationMatrixButton.Text = 'Correlation Matrix';
            app.CorrelationMatrixButton.Value = true;
            app.ROCcurveButton = uiradiobutton(app.FEATURESELECTIONButtonGroup);
            app.ROCcurveButton.Text = 'ROC curve';
            app.NoneButton_2 = uiradiobutton(app.FEATURESELECTIONButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.ShowgraphsButton = uibutton(app.FEATURESELECTIONButtonGroup, 'push');
            app.ShowgraphsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowgraphsButtonPushed, true);
            app.ShowgraphsButton.Text = 'Show graphs';
            app.HeartDiseasePredictionLabel = uilabel(app.UIFigure);
            app.HeartDiseasePredictionLabel.Text = 'Heart Disease Prediction';
            app.CatarinaPintoGabrielPinheiroJulianaFariaLabel = uilabel(app.UIFigure);
            app.CatarinaPintoGabrielPinheiroJulianaFariaLabel.Text = 'Catarina Pinto | Gabriel Pinheiro | Juliana Faria ';
            app.SCENARIOButtonGroup = uibuttongroup(app.UIFigure);
            app.SCENARIOButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SCENARIOButtonGroupSelectionChanged, true);
            app.SCENARIOButtonGroup.Tooltip = {''};
            app.SCENARIOButtonGroup.Title = 'SCENARIO';
            app.AButton = uiradiobutton(app.SCENARIOButtonGroup);
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uiradiobutton(app.SCENARIOButtonGroup);
            app.BButton.Text = 'B';
            app.CButton = uiradiobutton(app.SCENARIOButtonGroup);
            app.CButton.Text = 'C';
            app.PatternRecognition2122Label = uilabel(app.UIFigure);
            app.PatternRecognition2122Label.Text = 'Pattern Recognition 21/22';
            app.DIMENSIONALITYREDUCTIONButtonGroup = uibuttongroup(app.UIFigure);
            app.DIMENSIONALITYREDUCTIONButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DIMENSIONALITYREDUCTIONButtonGroupSelectionChanged, true);
            app.DIMENSIONALITYREDUCTIONButtonGroup.Tooltip = {''};
            app.DIMENSIONALITYREDUCTIONButtonGroup.Title = 'DIMENSIONALITY REDUCTION';
            app.PCAButton = uiradiobutton(app.DIMENSIONALITYREDUCTIONButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.PCAButton.Value = true;
            app.LDAButton = uiradiobutton(app.DIMENSIONALITYREDUCTIONButtonGroup);
            app.LDAButton.Text = 'LDA';
            app.NumberofcomponentsSliderLabel = uilabel(app.DIMENSIONALITYREDUCTIONButtonGroup);
            app.NumberofcomponentsSliderLabel.Text = 'Number of components:';
            app.NumberofcomponentsSlider = uislider(app.DIMENSIONALITYREDUCTIONButtonGroup);
            app.NumberofcomponentsSlider.Limits = [1 15];
            app.NumberofcomponentsSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.NumberofcomponentsSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.NumberofcomponentsSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofcomponentsSliderValueChanged, true);
            app.NumberofcomponentsSlider.MinorTicks = [];
            app.NumberofcomponentsSlider.Value = 9;
            app.NoneButton_3 = uiradiobutton(app.DIMENSIONALITYREDUCTIONButtonGroup);
            app.NoneButton_3.Text = 'None';
            app.CLASSIFIERButtonGroup = uibuttongroup(app.UIFigure);
            app.CLASSIFIERButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CLASSIFIERButtonGroupSelectionChanged, true);
            app.CLASSIFIERButtonGroup.Tooltip = {''};
            app.CLASSIFIERButtonGroup.Title = 'CLASSIFIER';
            app.MinimumDistanceClassifierEuclideanButton = uiradiobutton(app.CLASSIFIERButtonGroup);
            app.MinimumDistanceClassifierEuclideanButton.Text = 'Minimum Distance Classifier - Euclidean';
            app.MinimumDistanceClassifierEuclideanButton.Value = true;
            app.MinimumDistanceClassifierMahalanobisButton = uiradiobutton(app.CLASSIFIERButtonGroup);
            app.MinimumDistanceClassifierMahalanobisButton.Text = 'Minimum Distance Classifier - Mahalanobis';
            app.FisherLDAButton = uiradiobutton(app.CLASSIFIERButtonGroup);
            app.FisherLDAButton.Text = 'Fisher LDA';
            app.KNearestNeighborsButton = uiradiobutton(app.CLASSIFIERButtonGroup);
            app.KNearestNeighborsButton.Text = 'K-Nearest Neighbors';
            app.SupportVectorsMachineLinearButton = uiradiobutton(app.CLASSIFIERButtonGroup);
            app.SupportVectorsMachineLinearButton.Text = 'Support Vectors Machine - Linear';
            app.NaiveBayesButton = uiradiobutton(app.CLASSIFIERButtonGroup);
            app.NaiveBayesButton.Text = 'Naive Bayes';
            app.SupportVectorsMachineNonlinearButton = uiradiobutton(app.CLASSIFIERButtonGroup);
            app.SupportVectorsMachineNonlinearButton.Text = 'Support Vectors Machine - Non-linear';
            app.NumberofneighborsSliderLabel = uilabel(app.CLASSIFIERButtonGroup);
            app.NumberofneighborsSliderLabel.Enable = 'off';
            app.NumberofneighborsSliderLabel.Text = 'Number of neighbors:';
            app.NumberofneighborsSlider = uislider(app.CLASSIFIERButtonGroup);
            app.NumberofneighborsSlider.Limits = [1 15];
            app.NumberofneighborsSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.NumberofneighborsSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.NumberofneighborsSlider.MinorTicks = [];
            app.NumberofneighborsSlider.Enable = 'off';
            app.NumberofneighborsSlider.Value = 1;
            app.TRAINButton = uibutton(app.CLASSIFIERButtonGroup, 'push');
            app.TRAINButton.ButtonPushedFcn = createCallbackFcn(app, @TRAINButtonPushed, true);
            app.TRAINButton.Text = 'TRAIN';
            app.HELPButton = uibutton(app.UIFigure, 'push');
            app.HELPButton.ButtonPushedFcn = createCallbackFcn(app, @HELPButtonPushed, true);
            app.HELPButton.Text = 'HELP';
            app.FEATURESTOSELECTButtonGroup = uibuttongroup(app.UIFigure);
            app.FEATURESTOSELECTButtonGroup.Tooltip = {''};
            app.FEATURESTOSELECTButtonGroup.Title = 'FEATURES TO SELECT';
            app.SexCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.SexCheckBox.ValueChangedFcn = createCallbackFcn(app, @SexCheckBoxValueChanged, true);
            app.SexCheckBox.Text = 'Sex';
            app.DiabeticCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.DiabeticCheckBox.ValueChangedFcn = createCallbackFcn(app, @DiabeticCheckBoxValueChanged, true);
            app.DiabeticCheckBox.Text = 'Diabetic';
            app.PhysicalActivityCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.PhysicalActivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhysicalActivityCheckBoxValueChanged, true);
            app.PhysicalActivityCheckBox.Text = 'PhysicalActivity';
            app.StrokeCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.StrokeCheckBox.ValueChangedFcn = createCallbackFcn(app, @StrokeCheckBoxValueChanged, true);
            app.StrokeCheckBox.Text = 'Stroke';
            app.BMI5CheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.BMI5CheckBox.ValueChangedFcn = createCallbackFcn(app, @BMI5CheckBoxValueChanged, true);
            app.BMI5CheckBox.Text = 'BMI5';
            app.SmokingCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.SmokingCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmokingCheckBoxValueChanged, true);
            app.SmokingCheckBox.Text = 'Smoking';
            app.AlcoholDrinkingCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.AlcoholDrinkingCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlcoholDrinkingCheckBoxValueChanged, true);
            app.AlcoholDrinkingCheckBox.Text = 'AlcoholDrinking';
            app.MentalHealthCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.MentalHealthCheckBox.ValueChangedFcn = createCallbackFcn(app, @MentalHealthCheckBoxValueChanged, true);
            app.MentalHealthCheckBox.Text = 'MentalHealth';
            app.PhysicalHealthCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.PhysicalHealthCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhysicalHealthCheckBoxValueChanged, true);
            app.PhysicalHealthCheckBox.Text = 'PhysicalHealth';
            app.RaceCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.RaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @RaceCheckBoxValueChanged, true);
            app.RaceCheckBox.Text = 'Race';
            app.DiffWalkingCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.DiffWalkingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DiffWalkingCheckBoxValueChanged, true);
            app.DiffWalkingCheckBox.Text = 'DiffWalking';
            app.AgeCategoryCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.AgeCategoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @AgeCategoryCheckBoxValueChanged, true);
            app.AgeCategoryCheckBox.Text = 'AgeCategory';
            app.GenHealthCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.GenHealthCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenHealthCheckBoxValueChanged, true);
            app.GenHealthCheckBox.Text = 'GenHealth';
            app.SleepTimeCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.SleepTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SleepTimeCheckBoxValueChanged, true);
            app.SleepTimeCheckBox.Text = 'SleepTime';
            app.AsthmaCheckBox = uicheckbox(app.FEATURESTOSELECTButtonGroup);
            app.AsthmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AsthmaCheckBoxValueChanged, true);
            app.AsthmaCheckBox.Text = 'Asthma';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUITrain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end